classdef VentanasInteres < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        EPILECTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        GrficayventanasdeintersdelasealLabel  matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        Label_Test             matlab.ui.control.Label
        VentanaEditFieldLabel  matlab.ui.control.Label
        VentanaEditField       matlab.ui.control.NumericEditField
        Boton_Anterior         matlab.ui.control.Button
        Boton_Siguiente        matlab.ui.control.Button
        VentanaanteriorLabel   matlab.ui.control.Label
        VentanasiguienteLabel  matlab.ui.control.Label
        AnotacionesLabel       matlab.ui.control.Label
        TextoAnotacion         matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        HoradeiniciodelregistroEEGHHMMSSEditFieldLabel  matlab.ui.control.Label
        HoradeiniciodelregistroEEGHHMMSSEditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        labels
        signal
        no_window
        window_samples
        fs_plot
        vector_ict
        vector_sano 
        no_window_road = 1;
        sweepict = 1;
        sweepsano = 1;
        %App que abre esta app
        CallingApp
        
    end
    
    properties (Access = public)
        %Variables compartidas entre ventanas 
        Vector_Car_EEGVI
        EEG_VI
        Intervalo_Ventana_s
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;    
            record_interes = app.CallingApp.EEG_VI(1,1:300);
            time_series = linspace(0,1,length(record_interes));
            plot(app.UIAxes,time_series,record_interes);
        end

        % Button pushed function: Boton_Anterior
        function Boton_AnteriorButtonPushed(app, event)
%             if (app.no_window_road == 1)
%                 app.no_window_road = length(app.vector_ict);
%             else
%                 app.no_window_road = app.no_window_road-1;
%             end
%             app.no_window = app.vector_ict(1,app.no_window_road);
%             ventana_interes = app.window_samples*app.no_window;
%             record_interes = app.signal(1,ventana_interes-app.window_samples:ventana_interes);
%             time_series = linspace(0,1,length(record_interes));
%             %plot(app.UIAxes,time_series,record_interes);
            
        end

        % Button pushed function: Boton_Siguiente
        function Boton_SiguienteButtonPushed(app, event)
%             app.no_window_road
%             if (app.no_window_road == length(app.vector_ict))
%                 app.no_window_road = 1;
%             else
%                 app.no_window_road = app.no_window_road+1;
%             end
%             app.no_window = app.vector_ict(1,app.no_window_road);
%             ventana_interes = app.window_samples*app.no_window;
%             record_interes = app.signal(1,ventana_interes-app.window_samples:ventana_interes);
%             time_series = linspace(0,1,length(record_interes));
%             plot(app.UIAxes,time_series,record_interes);
%             app.VentanaEditField.Value = app.no_window;
%             app.TextoAnotacion.Text = "Ventana con señal ictal";
              %dox = app.CallingApp.Vector_Car_EEGVI
            %app.VentanaEditField.Value = app.no_window;
            t1 = hours(23.4167):seconds(60):hours(25);
            t = hours(t1);
            y = rand(1,length(t));
            plot(app.UIAxes,t,y);
            %plot(t,y);
            xticks_array = [hours(t1(1)),hours(t1(48)),hours(t1(95))];
            app.UIAxes.XTick = xticks_array;            
            app.UIAxes.XTickLabel ={datestr(t1(1),'HH:MM:SS'),datestr(t1(48),'HH:MM:SS'),datestr(t1(95),'HH:MM:SS')};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EPILECTICEEGANALYSISTOOLBOXLabel = uilabel(app.UIFigure);
            app.EPILECTICEEGANALYSISTOOLBOXLabel.Text = 'EPILECTIC EEG ANALYSIS TOOLBOX';
            app.GrficayventanasdeintersdelasealLabel = uilabel(app.UIFigure);
            app.GrficayventanasdeintersdelasealLabel.Text = 'Gráfica y ventanas de interés de la señal';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '/';
            app.Label_Test = uilabel(app.UIFigure);
            app.Label_Test.Text = 'de';
            app.VentanaEditFieldLabel = uilabel(app.UIFigure);
            app.VentanaEditFieldLabel.Text = 'Ventana';
            app.VentanaEditField = uieditfield(app.UIFigure, 'numeric');
            app.Boton_Anterior = uibutton(app.UIFigure, 'push');
            app.Boton_Anterior.ButtonPushedFcn = createCallbackFcn(app, @Boton_AnteriorButtonPushed, true);
            app.Boton_Anterior.Icon = 'Flecha_izquierda.jpg';
            app.Boton_Anterior.Text = '';
            app.Boton_Siguiente = uibutton(app.UIFigure, 'push');
            app.Boton_Siguiente.ButtonPushedFcn = createCallbackFcn(app, @Boton_SiguienteButtonPushed, true);
            app.Boton_Siguiente.Icon = 'Flecha_derecha.jpg';
            app.Boton_Siguiente.Text = '';
            app.VentanaanteriorLabel = uilabel(app.UIFigure);
            app.VentanaanteriorLabel.Text = 'Ventana anterior';
            app.VentanasiguienteLabel = uilabel(app.UIFigure);
            app.VentanasiguienteLabel.Text = 'Ventana siguiente';
            app.AnotacionesLabel = uilabel(app.UIFigure);
            app.AnotacionesLabel.Text = 'Anotaciones:';
            app.TextoAnotacion = uilabel(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal EEG')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'uV')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.HoradeiniciodelregistroEEGHHMMSSEditFieldLabel = uilabel(app.UIFigure);
            app.HoradeiniciodelregistroEEGHHMMSSEditFieldLabel.Text = {'Hora de inicio del registro EEG'; ' HH:MM:SS'};
            app.HoradeiniciodelregistroEEGHHMMSSEditField = uieditfield(app.UIFigure, 'text');
            app.HoradeiniciodelregistroEEGHHMMSSEditField.Value = '00:00:00';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VentanasInteres(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end